classdef UNANR_Matlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MainsignalTab                 matlab.ui.container.Tab
        Main_signal                   matlab.ui.control.UIAxes
        signal1toLabel                matlab.ui.control.Label
        Signal_to                     matlab.ui.control.Spinner
        Main_signal_Ftt               matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        N_fftLabel                    matlab.ui.control.Label
        N_Ftt                         matlab.ui.control.Spinner
        SignalSliderLabel             matlab.ui.control.Label
        SignalSlider                  matlab.ui.control.Slider
        N_fftSliderLabel              matlab.ui.control.Label
        N_fftSlider                   matlab.ui.control.Slider
        FrequencyresponseplotofIIRcombfilterTab  matlab.ui.container.Tab
        Magnitude_and_Phase           matlab.ui.control.UIAxes
        Group_delay                   matlab.ui.control.UIAxes
        Phase_delay                   matlab.ui.control.UIAxes
        Pole_zero                     matlab.ui.control.UIAxes
        Step                          matlab.ui.control.UIAxes
        Impulse                       matlab.ui.control.UIAxes
        CombfilterdesignPanel         matlab.ui.container.Panel
        ECGsamplingrateLabel          matlab.ui.control.Label
        fs                            matlab.ui.control.Spinner
        HzRemovalLabel                matlab.ui.control.Label
        fo                            matlab.ui.control.Spinner
        IIRQfactorLabel               matlab.ui.control.Label
        q                             matlab.ui.control.Spinner
        FilteredsignalsTab            matlab.ui.container.Tab
        filtered_fourie               matlab.ui.control.UIAxes
        Infectthesignalwith5dBnoisePanel  matlab.ui.container.Panel
        ECG_signal_with_5dbNoise      matlab.ui.control.UIAxes
        ECG_signal_filtered_with_LMS  matlab.ui.control.UIAxes
        ECG_signal_filtered_with_UNANAR  matlab.ui.control.UIAxes
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
    end


    methods (Access = private)
        
        function results = ploting(app)
            changingValue = 2000 ;
            load('ECG_modified.mat');
            Sig = ECG_BaseFree;
            cla(app.Main_signal)
            if ischar( changingValue )
                plot(app.Main_signal,Sig(1:str2num(changingValue)))
            else
                plot(app.Main_signal,Sig(1:changingValue))
            end
            title(app.Main_signal,'Main Signal Without Baseline')
            
            changingValue1 = 1024 ;
            N_fft=changingValue1;
            sig_fft=fft(Sig,N_fft);
            cla(app.Main_signal_Ftt)
            plot(app.Main_signal_Ftt , (-N_fft/2:N_fft/2-1)*2*180/N_fft,abs(fftshift(sig_fft)))
            xlabel(app.Main_signal_Ftt , 'Hz')
            
            
            fss = app.fs.Value;
            foo = app.fo.Value;
            qq = app.fo.Value ;
            bw = (foo/(fss/2))/qq;
            [bb,aa] = iircomb(round(fss/foo), bw, 'notch');
            %             app.b.Text = int2str(bb) ;
            %             app.a.text = int2str(aa) ;
            % fvtool(b, a)
            cla(app.Magnitude_and_Phase)
            [h,w] = freqz(bb,aa);
            yyaxis (app.Magnitude_and_Phase,'left')
            plot(app.Magnitude_and_Phase, w,20*log10(abs(h)))
            xlabel(app.Magnitude_and_Phase,'Frequency')
            ylabel(app.Magnitude_and_Phase,'Magnitude (dB)')
            yyaxis (app.Magnitude_and_Phase,'right')
            ylabel(app.Magnitude_and_Phase,'Phase (radians)')
            [ploti , phi] = phasez(bb , aa) ;
            plot(app.Magnitude_and_Phase ,phi, ploti)
            title(app.Magnitude_and_Phase, 'Magnitude Response (dB) and Phase Response')
            
            [ploti_for_grpdelay , phi1] = grpdelay(bb,aa);
            plot(app.Group_delay  ,phi1, ploti_for_grpdelay)
            %
            
            
            [ploti_for_phasedelay , phi2] = phasedelay (bb,aa);
            plot(app.Phase_delay  ,phi2, ploti_for_phasedelay)
            
            [ploti_for_impplus, phi3] = impz(bb,aa) ;
            plot(app.Impulse,phi3, ploti_for_impplus)


            [ploti_for_step, phi4] = stepz(bb,aa) ;
            plot(app.Step,phi4, ploti_for_step);


            [hw,fw] = zerophase(bb,aa,1024,"whole");
            
            z = roots(bb);
            p = roots(aa);
            
            plot3(app.Pole_zero,cos(fw),sin(fw),hw)
            hold(app.Pole_zero, 'on')
            plot3(app.Pole_zero,cos(fw),sin(fw),zeros(size(fw)),'--')
            plot3(app.Pole_zero,real(z),imag(z),zeros(size(z)),'o')
            plot3(app.Pole_zero,real(p),imag(p),zeros(size(p)),'x')
            hold(app.Pole_zero, 'off')
            xlabel(app.Pole_zero,"Real")
            ylabel(app.Pole_zero,"Imaginary")
            view(app.Pole_zero,35,40)
            
            
            [bb,aa] = iircomb(round(app.fs.Value/app.fo.Value), bw, 'notch');
            Signal_Comb = filter(bb, aa, Sig);
            sig_comb_fft=fft(Signal_Comb,N_fft);
            plot(app.filtered_fourie,(-N_fft/2:N_fft/2-1)*2*180/N_fft,abs(fftshift(sig_comb_fft)))
            xlabel(app.filtered_fourie,'Hz')


            ECG_Clean = Signal_Comb;
            SignalPower = mean(ECG_Clean.^2);
            Reference_Input = Noise_5dB;
            Primary_Input = ECG_Clean + Noise_5dB;
            
            
            % Adaptive filtering
            % LMS adaptive filter
            StepSize_LMS = 0.5;
            % H_LMS1 = adaptfilt.lms(10, StepSize_LMS);
            % [Error_LMS] = filter(H_LMS, Reference_Input, Primary_Input);
            % Gain_LMS = sum(H_LMS.Coefficients);
            % Error_LMS = Error_LMS ./ Gain_LMS;
            
            H_LMS = dsp.LMSFilter(11 , 'StepSize', StepSize_LMS);
            [y,e,w] = step(H_LMS, Reference_Input, Primary_Input);
            Gain_LMS = sum(w);
            Error_LMS = e./ Gain_LMS ;
            
            Output_LMS = Error_LMS;
            ECG_LMS = Primary_Input - y;
            
            % UNANR filter
            Lr_UNANR = 0.5;
            [Output_UNANR, W_UNANR] = UNANR(SignalDelay(Reference_Input, 10), Primary_Input, Lr_UNANR); % 10-order UNANR filter
            ECG_UNANR = Primary_Input - Output_UNANR;
            Error_UNANR = ECG_UNANR - ECG_Clean;
            plot(app.ECG_signal_with_5dbNoise,Time, Primary_Input, 'k');
            axis (app.ECG_signal_with_5dbNoise,[0, 10, -0.5, 1.3]);
            plot(app.ECG_signal_filtered_with_LMS,Time, ECG_LMS, 'k');
            axis (app.ECG_signal_filtered_with_LMS,[0, 10, -0.5, 1.3]);
            plot(app.ECG_signal_filtered_with_UNANAR,Time, ECG_UNANR, 'k');
            axis (app.ECG_signal_filtered_with_UNANAR,[0, 10, -0.5, 1.3]);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ploting ;
        end

        % Value changing function: Signal_to
        function Signal_toValueChanging(app, event)
            changingValue = event.Value;
            load('ECG_modified.mat');
            Sig = ECG_BaseFree;
            cla(app.Main_signal)
            if ischar( changingValue )
                plot(app.Main_signal,Sig(1:str2num(changingValue)))
            else
                plot(app.Main_signal,Sig(1:changingValue))
            end
            title(app.Main_signal,'Main Signal Without Baseline')
        end

        % Value changing function: SignalSlider
        function SignalSliderValueChanging(app, event)
            changingValue = event.Value;
            load('ECG_modified.mat');
            Sig = ECG_BaseFree;
            cla(app.Main_signal)
            plot(app.Main_signal,Sig(1:changingValue))
            title(app.Main_signal,'Main Signal Without Baseline')
            app.Signal_to.Value=changingValue ;
        end

        % Value changing function: N_Ftt
        function N_FttValueChanging(app, event)
            changingValue = event.Value;
            if ischar( changingValue )
                changingValue = str2num(changingValue) ;
            end
            load('ECG_modified.mat');
            Sig = ECG_BaseFree;
            N_fft=changingValue;
            sig_fft=fft(Sig,N_fft);
            cla(app.Main_signal_Ftt)
            plot(app.Main_signal_Ftt , (-N_fft/2:N_fft/2-1)*2*180/N_fft,abs(fftshift(sig_fft)))
            xlabel(app.Main_signal_Ftt , 'Hz')
        end

        % Value changing function: N_fftSlider
        function N_fftSliderValueChanging(app, event)
            changingValue = event.Value;
            if ischar( changingValue )
                changingValue = str2num(changingValue) ;
            end
            load('ECG_modified.mat');
            Sig = ECG_BaseFree;
            N_fft=changingValue;
            sig_fft=fft(Sig,N_fft);
            cla(app.Main_signal_Ftt)
            plot(app.Main_signal_Ftt , (-N_fft/2:N_fft/2-1)*2*180/N_fft,abs(fftshift(sig_fft)))
            xlabel(app.Main_signal_Ftt , 'Hz')
            app.N_Ftt.Value = changingValue ;
        end

        % Value changing function: fs
        function fsValueChanging(app, event)
            changingValue = event.Value;
            if ischar(changingValue)
                changingValue = str2num(changingValue) ;
            end
            app.fs.Value = changingValue;
            fss = app.fs.Value;
            foo = app.fo.Value;
            qq = app.q.Value ;
            bw = (foo/(fss/2))/qq;
            [bb,aa] = iircomb(round(fss/foo), bw, 'notch');
            %             app.b.Text = int2str(bb) ;
            %             app.a.text = int2str(aa) ;
            % fvtool(b, a)
            cla(app.Magnitude_and_Phase)
            [h,w] = freqz(bb,aa);
            yyaxis (app.Magnitude_and_Phase,'left')
            plot(app.Magnitude_and_Phase, w,20*log10(abs(h)))
            xlabel(app.Magnitude_and_Phase,'Frequency')
            ylabel(app.Magnitude_and_Phase,'Magnitude (dB)')
            yyaxis (app.Magnitude_and_Phase,'right')
            ylabel(app.Magnitude_and_Phase,'Phase (radians)')
            [ploti , phi] = phasez(bb , aa) ;
            plot(app.Magnitude_and_Phase ,phi, ploti)
            title(app.Magnitude_and_Phase, 'Magnitude Response (dB) and Phase Response')
            
            [ploti_for_grpdelay , phi1] = grpdelay(bb,aa);
            plot(app.Group_delay  ,phi1, ploti_for_grpdelay)
            %
            
            
            [ploti_for_phasedelay , phi2] = phasedelay (bb,aa);
            plot(app.Phase_delay  ,phi2, ploti_for_phasedelay)
            
            [ploti_for_impplus, phi3] = impz(bb,aa) ;
            plot(app.Impulse,phi3, ploti_for_impplus)


            [ploti_for_step, phi4] = stepz(bb,aa) ;
            plot(app.Step,phi4, ploti_for_step);


            [hw,fw] = zerophase(bb,aa,1024,"whole");
            
            z = roots(bb);
            p = roots(aa);
            
            plot3(app.Pole_zero,cos(fw),sin(fw),hw)
            hold(app.Pole_zero, 'on')
            plot3(app.Pole_zero,cos(fw),sin(fw),zeros(size(fw)),'--')
            plot3(app.Pole_zero,real(z),imag(z),zeros(size(z)),'o')
            plot3(app.Pole_zero,real(p),imag(p),zeros(size(p)),'x')
            hold(app.Pole_zero, 'off')
            xlabel(app.Pole_zero,"Real")
            ylabel(app.Pole_zero,"Imaginary")
            view(app.Pole_zero,35,40)
            
            
        end

        % Value changing function: fo
        function foValueChanging(app, event)
            changingValue = event.Value;
            if ischar(changingValue)
                changingValue = str2num(changingValue) ;
            end
            app.fo.Value = changingValue;
            fss = app.fs.Value;
            foo = app.fo.Value;
            qq = app.q.Value ;
            bw = (foo/(fss/2))/qq;
            [bb,aa] = iircomb(round(fss/foo), bw, 'notch');
            %             app.b.Text = int2str(bb) ;
            %             app.a.text = int2str(aa) ;
            % fvtool(b, a)
            cla(app.Magnitude_and_Phase)
            [h,w] = freqz(bb,aa);
            yyaxis (app.Magnitude_and_Phase,'left')
            plot(app.Magnitude_and_Phase, w,20*log10(abs(h)))
            xlabel(app.Magnitude_and_Phase,'Frequency')
            ylabel(app.Magnitude_and_Phase,'Magnitude (dB)')
            yyaxis (app.Magnitude_and_Phase,'right')
            ylabel(app.Magnitude_and_Phase,'Phase (radians)')
            [ploti , phi] = phasez(bb , aa) ;
            plot(app.Magnitude_and_Phase ,phi, ploti)
            title(app.Magnitude_and_Phase, 'Magnitude Response (dB) and Phase Response')
            
            [ploti_for_grpdelay , phi1] = grpdelay(bb,aa);
            plot(app.Group_delay  ,phi1, ploti_for_grpdelay)
            %
            
            
            [ploti_for_phasedelay , phi2] = phasedelay (bb,aa);
            plot(app.Phase_delay  ,phi2, ploti_for_phasedelay)
            
            [ploti_for_impplus, phi3] = impz(bb,aa) ;
            plot(app.Impulse,phi3, ploti_for_impplus)


            [ploti_for_step, phi4] = stepz(bb,aa) ;
            plot(app.Step,phi4, ploti_for_step);


            [hw,fw] = zerophase(bb,aa,1024,"whole");
            
            z = roots(bb);
            p = roots(aa);
            
            plot3(app.Pole_zero,cos(fw),sin(fw),hw)
            hold(app.Pole_zero, 'on')
            plot3(app.Pole_zero,cos(fw),sin(fw),zeros(size(fw)),'--')
            plot3(app.Pole_zero,real(z),imag(z),zeros(size(z)),'o')
            plot3(app.Pole_zero,real(p),imag(p),zeros(size(p)),'x')
            hold(app.Pole_zero, 'off')
            xlabel(app.Pole_zero,"Real")
            ylabel(app.Pole_zero,"Imaginary")
            view(app.Pole_zero,35,40)
        end

        % Value changing function: q
        function qValueChanging(app, event)
            changingValue = event.Value;
            if ischar(changingValue)
                changingValue = str2num(changingValue) ;
            end
            app.q.Value = changingValue;
            fss = app.fs.Value;
            foo = app.fo.Value;
            qq = app.q.Value ;
            bw = (foo/(fss/2))/qq;
            [bb,aa] = iircomb(round(fss/foo), bw, 'notch');
            %             app.b.Text = int2str(bb) ;
            %             app.a.text = int2str(aa) ;
            % fvtool(b, a)
            cla(app.Magnitude_and_Phase)
            [h,w] = freqz(bb,aa);
            yyaxis (app.Magnitude_and_Phase,'left')
            plot(app.Magnitude_and_Phase, w,20*log10(abs(h)))
            xlabel(app.Magnitude_and_Phase,'Frequency')
            ylabel(app.Magnitude_and_Phase,'Magnitude (dB)')
            yyaxis (app.Magnitude_and_Phase,'right')
            ylabel(app.Magnitude_and_Phase,'Phase (radians)')
            [ploti , phi] = phasez(bb , aa) ;
            plot(app.Magnitude_and_Phase ,phi, ploti)
            title(app.Magnitude_and_Phase, 'Magnitude Response (dB) and Phase Response')
            
            [ploti_for_grpdelay , phi1] = grpdelay(bb,aa);
            plot(app.Group_delay  ,phi1, ploti_for_grpdelay)
            %
            
            
            [ploti_for_phasedelay , phi2] = phasedelay (bb,aa);
            plot(app.Phase_delay  ,phi2, ploti_for_phasedelay)
            
            [ploti_for_impplus, phi3] = impz(bb,aa) ;
            plot(app.Impulse,phi3, ploti_for_impplus)


            [ploti_for_step, phi4] = stepz(bb,aa) ;
            plot(app.Step,phi4, ploti_for_step);


            [hw,fw] = zerophase(bb,aa,1024,"whole");
            
            z = roots(bb);
            p = roots(aa);
            
            plot3(app.Pole_zero,cos(fw),sin(fw),hw)
            hold(app.Pole_zero, 'on')
            plot3(app.Pole_zero,cos(fw),sin(fw),zeros(size(fw)),'--')
            plot3(app.Pole_zero,real(z),imag(z),zeros(size(z)),'o')
            plot3(app.Pole_zero,real(p),imag(p),zeros(size(p)),'x')
            hold(app.Pole_zero, 'off')
            xlabel(app.Pole_zero,"Real")
            ylabel(app.Pole_zero,"Imaginary")
            view(app.Pole_zero,35,40)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainsignalTab = uitab(app.TabGroup);
            app.MainsignalTab.Title = 'Main signal';
            app.Main_signal = uiaxes(app.MainsignalTab);
            title(app.Main_signal, 'Main signal without Baseline')
            xlabel(app.Main_signal, '')
            ylabel(app.Main_signal, '')
            app.signal1toLabel = uilabel(app.MainsignalTab);
            app.signal1toLabel.Text = 'signal 1 to : ';
            app.Signal_to = uispinner(app.MainsignalTab);
            app.Signal_to.Step = 10;
            app.Signal_to.ValueChangingFcn = createCallbackFcn(app, @Signal_toValueChanging, true);
            app.Signal_to.Limits = [2 Inf];
            app.Signal_to.Value = 2000;
            app.Main_signal_Ftt = uiaxes(app.MainsignalTab);
            title(app.Main_signal_Ftt, 'Main Signal FFt')
            xlabel(app.Main_signal_Ftt, 'HZ')
            ylabel(app.Main_signal_Ftt, '')
            app.Label = uilabel(app.MainsignalTab);
            app.Label.Text = 'Fourier signal conversion display and annoying 60 Hz harmonics due to city noise.';
            app.N_fftLabel = uilabel(app.MainsignalTab);
            app.N_fftLabel.Text = 'N_fft =';
            app.N_Ftt = uispinner(app.MainsignalTab);
            app.N_Ftt.Step = 10;
            app.N_Ftt.ValueChangingFcn = createCallbackFcn(app, @N_FttValueChanging, true);
            app.N_Ftt.Limits = [1 Inf];
            app.N_Ftt.Value = 1024;
            app.SignalSliderLabel = uilabel(app.MainsignalTab);
            app.SignalSliderLabel.Text = 'Signal';
            app.SignalSlider = uislider(app.MainsignalTab);
            app.SignalSlider.Limits = [1 10000];
            app.SignalSlider.ValueChangingFcn = createCallbackFcn(app, @SignalSliderValueChanging, true);
            app.SignalSlider.Value = 1000;
            app.N_fftSliderLabel = uilabel(app.MainsignalTab);
            app.N_fftSliderLabel.Text = 'N_fft';
            app.N_fftSlider = uislider(app.MainsignalTab);
            app.N_fftSlider.Limits = [1 10000];
            app.N_fftSlider.ValueChangingFcn = createCallbackFcn(app, @N_fftSliderValueChanging, true);
            app.N_fftSlider.Value = 1024;
            app.FrequencyresponseplotofIIRcombfilterTab = uitab(app.TabGroup);
            app.FrequencyresponseplotofIIRcombfilterTab.Title = 'Frequency response plot of IIR comb filter';
            app.Magnitude_and_Phase = uiaxes(app.FrequencyresponseplotofIIRcombfilterTab);
            title(app.Magnitude_and_Phase, 'Title')
            xlabel(app.Magnitude_and_Phase, 'X')
            ylabel(app.Magnitude_and_Phase, 'Y')
            app.Group_delay = uiaxes(app.FrequencyresponseplotofIIRcombfilterTab);
            title(app.Group_delay, 'Group delay')
            xlabel(app.Group_delay, 'Frequency')
            ylabel(app.Group_delay, 'Group delay (in samples)')
            app.Phase_delay = uiaxes(app.FrequencyresponseplotofIIRcombfilterTab);
            title(app.Phase_delay, 'Phase Delay')
            xlabel(app.Phase_delay, 'Frequency')
            ylabel(app.Phase_delay, 'Phase Delay (Smaples)')
            app.Pole_zero = uiaxes(app.FrequencyresponseplotofIIRcombfilterTab);
            title(app.Pole_zero, 'Pile-Zero')
            xlabel(app.Pole_zero, 'X')
            ylabel(app.Pole_zero, 'Y')
            app.Step = uiaxes(app.FrequencyresponseplotofIIRcombfilterTab);
            title(app.Step, 'Step Response')
            xlabel(app.Step, 'Samples')
            ylabel(app.Step, 'Amplitude')
            app.Impulse = uiaxes(app.FrequencyresponseplotofIIRcombfilterTab);
            title(app.Impulse, 'Impulse Response')
            xlabel(app.Impulse, 'Samples')
            ylabel(app.Impulse, 'Amplitude')
            app.CombfilterdesignPanel = uipanel(app.FrequencyresponseplotofIIRcombfilterTab);
            app.CombfilterdesignPanel.Title = 'Comb filter design';
            app.ECGsamplingrateLabel = uilabel(app.CombfilterdesignPanel);
            app.ECGsamplingrateLabel.Text = 'ECG sampling rate :';
            app.fs = uispinner(app.CombfilterdesignPanel);
            app.fs.ValueChangingFcn = createCallbackFcn(app, @fsValueChanging, true);
            app.fs.Limits = [0 Inf];
            app.fs.Value = 360;
            app.HzRemovalLabel = uilabel(app.CombfilterdesignPanel);
            app.HzRemovalLabel.Text = 'Hz Removal : ';
            app.fo = uispinner(app.CombfilterdesignPanel);
            app.fo.ValueChangingFcn = createCallbackFcn(app, @foValueChanging, true);
            app.fo.Limits = [0 Inf];
            app.fo.Value = 60;
            app.IIRQfactorLabel = uilabel(app.CombfilterdesignPanel);
            app.IIRQfactorLabel.Text = 'IIR Q factor : ';
            app.q = uispinner(app.CombfilterdesignPanel);
            app.q.ValueChangingFcn = createCallbackFcn(app, @qValueChanging, true);
            app.q.Limits = [0 Inf];
            app.q.Value = 30;
            app.FilteredsignalsTab = uitab(app.TabGroup);
            app.FilteredsignalsTab.Title = 'Filtered signals';
            app.filtered_fourie = uiaxes(app.FilteredsignalsTab);
            title(app.filtered_fourie, 'Title')
            xlabel(app.filtered_fourie, 'X')
            ylabel(app.filtered_fourie, 'Y')
            app.Infectthesignalwith5dBnoisePanel = uipanel(app.FilteredsignalsTab);
            app.Infectthesignalwith5dBnoisePanel.Title = 'Infect the signal with 5dB noise';
            app.ECG_signal_with_5dbNoise = uiaxes(app.Infectthesignalwith5dBnoisePanel);
            title(app.ECG_signal_with_5dbNoise, 'Primary Input')
            xlabel(app.ECG_signal_with_5dbNoise, {'Time (s)'; '(a)'})
            ylabel(app.ECG_signal_with_5dbNoise, 'Amplitude (mV)')
            app.ECG_signal_filtered_with_LMS = uiaxes(app.Infectthesignalwith5dBnoisePanel);
            title(app.ECG_signal_filtered_with_LMS, 'LMS Output')
            xlabel(app.ECG_signal_filtered_with_LMS, {'Time (s)'; '(b)'})
            ylabel(app.ECG_signal_filtered_with_LMS, 'Amplitude (mV)')
            app.ECG_signal_filtered_with_UNANAR = uiaxes(app.Infectthesignalwith5dBnoisePanel);
            title(app.ECG_signal_filtered_with_UNANAR, 'UNANR Output')
            xlabel(app.ECG_signal_filtered_with_UNANAR, {'Time (s)'; '(c)'})
            ylabel(app.ECG_signal_filtered_with_UNANAR, 'Amplitude (mV)')
            app.Label_2 = uilabel(app.FilteredsignalsTab);
            app.Label_2.Text = {'Calculate and display the Fourier transform of the '; 'filtered heart signal'};
            app.Label_3 = uilabel(app.FilteredsignalsTab);
            app.Label_3.Text = {'It can be observed that at the different '; 'frequencies there are no other components.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UNANR_Matlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end